classdef AppCmd_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                    matlab.ui.Figure
        MainGrid                      matlab.ui.container.GridLayout
        ConsolePanel                  matlab.ui.container.Panel
        ConsoleGrid                   matlab.ui.container.GridLayout
        CmdWindow                     matlab.ui.control.TextArea
        SendButton                    matlab.ui.control.Button
        ConsoleText                   matlab.ui.control.EditField
        ComPanel                      matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        DataCom                       matlab.ui.container.Panel
        DataGrid                      matlab.ui.container.GridLayout
        Lamp_2                        matlab.ui.control.Lamp
        ConnectData                   matlab.ui.control.StateButton
        BaudRateData                  matlab.ui.control.NumericEditField
        BaudRateLabel                 matlab.ui.control.Label
        SelectData                    matlab.ui.control.DropDown
        ControlCom                    matlab.ui.container.Panel
        ControlGrid                   matlab.ui.container.GridLayout
        Lamp                          matlab.ui.control.Lamp
        ConnectControl                matlab.ui.control.StateButton
        BaudRateControl               matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel        matlab.ui.control.Label
        SelectControl                 matlab.ui.control.DropDown
        CmdPanel                      matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        DirectCMDTab                  matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
        Channel                       matlab.ui.control.NumericEditField
        ChannelLabel                  matlab.ui.control.Label
        SendDcmd                      matlab.ui.control.Button
        Data                          matlab.ui.control.NumericEditField
        DatatoWritehexLabel           matlab.ui.control.Label
        Address                       matlab.ui.control.NumericEditField
        AddressLabel                  matlab.ui.control.Label
        SelectDcmd                    matlab.ui.control.ListBox
        IniTab                        matlab.ui.container.Tab
        GridLayout6                   matlab.ui.container.GridLayout
        R17                           matlab.ui.control.EditField
        IndividualAmplifierPowerR17Label  matlab.ui.control.Label
        R16                           matlab.ui.control.EditField
        IndividualAmplifierPowerR14Label_2  matlab.ui.control.Label
        R15                           matlab.ui.control.EditField
        IndividualAmplifierPowerR14Label  matlab.ui.control.Label
        R14                           matlab.ui.control.EditField
        IndividualAmplifierPoweR14Label  matlab.ui.control.Label
        R13                           matlab.ui.control.EditField
        OnChipAmplifierBandwdthSelectR13Label  matlab.ui.control.Label
        R12                           matlab.ui.control.EditField
        OnChipAmplifierBandwdthSelectR12Label  matlab.ui.control.Label
        R11                           matlab.ui.control.EditField
        OnChipAmplifierBandwdthSelectR11Label  matlab.ui.control.Label
        R10                           matlab.ui.control.EditField
        OnChipAmplifierBandwdthSelectR10Label  matlab.ui.control.Label
        R09                           matlab.ui.control.EditField
        OnChipAmplifierBandwdthSelectR09Label  matlab.ui.control.Label
        R08                           matlab.ui.control.EditField
        OnChipAmplifierBandwdthSelectR08Label  matlab.ui.control.Label
        R07                           matlab.ui.control.EditField
        ImpedanceCheckAmplifierSelectLabel  matlab.ui.control.Label
        R06                           matlab.ui.control.EditField
        ImpedanceCheckDACLabel        matlab.ui.control.Label
        R05                           matlab.ui.control.EditField
        ImpedanceCheckControlLabel    matlab.ui.control.Label
        R04                           matlab.ui.control.EditField
        ADCOutputyFormatandDSPOffsetRemovalLabel  matlab.ui.control.Label
        R03                           matlab.ui.control.EditField
        MUXLoadTemperatureSensorandAuxiliaryDigitalOutputLabel  matlab.ui.control.Label
        R02                           matlab.ui.control.EditField
        MUXBiasCurrentLabel           matlab.ui.control.Label
        R01                           matlab.ui.control.EditField
        SupplySensorandADCBufferBiasCurrentLabel  matlab.ui.control.Label
        R00                           matlab.ui.control.EditField
        ADCConfigurationandAmplifierFastSettleLabel  matlab.ui.control.Label
        GridLayout7                   matlab.ui.container.GridLayout
        LoadConfigButton              matlab.ui.control.Button
        DefaultConfigButton           matlab.ui.control.Button
        ChLoopTab                     matlab.ui.container.Tab
        ChLoopGrid                    matlab.ui.container.GridLayout
        FrecuenciaestimulacinHzLabel  matlab.ui.control.Label
        Fstim                         matlab.ui.control.NumericEditField
        Lamp_3                        matlab.ui.control.Lamp
        CheckBox_2                    matlab.ui.control.CheckBox
        CheckBox_1                    matlab.ui.control.CheckBox
        StartcaptureButton            matlab.ui.control.Button
        TfullSample                   matlab.ui.control.NumericEditField
        DuracinmximadelamuestrasEditFieldLabel  matlab.ui.control.Label
        CheckBox_4                    matlab.ui.control.CheckBox
        CheckBox_3                    matlab.ui.control.CheckBox
        ChannelSelectorPanel          matlab.ui.container.Panel
        GridChannelsSelection         matlab.ui.container.GridLayout
        LoadConfigurationButton       matlab.ui.control.Button
        Tsave                         matlab.ui.control.NumericEditField
        PeriododecapturaporestimulacinmusLabel  matlab.ui.control.Label
        Fs                            matlab.ui.control.NumericEditField
        FrecuenciademuestreoHzEditFieldLabel  matlab.ui.control.Label
        Tblnk                         matlab.ui.control.NumericEditField
        PeriodoblankingmusLabel       matlab.ui.control.Label
        LoopPlotTab                   matlab.ui.container.Tab
        RTGrid                        matlab.ui.container.GridLayout
        UIAxes                        matlab.ui.control.UIAxes
        FileConfigTab                 matlab.ui.container.Tab
        GridLayout5                   matlab.ui.container.GridLayout
        EliminateDCCheckBox           matlab.ui.control.CheckBox
        SavetoFileButton              matlab.ui.control.Button
        FilenameEditField             matlab.ui.control.EditField
        FilenameEditFieldLabel        matlab.ui.control.Label
        ChangepathButton              matlab.ui.control.Button
        PathtofileEditField           matlab.ui.control.EditField
        PathtofileEditFieldLabel      matlab.ui.control.Label
    end

    %to-do: -Hay que hacer que guarde antes de cerrar
    %       -Que si se guarda uno se cambie el nombre del fichero y se
    %       vuelva a iniciar la cuenta del Record
    
    properties (Access = private)
        sData = [] % Serial Port connection for Data
        sControl = [] % Serial Port Connection for control
        action = [] %Array para construir los comandos
        Cmd = struct()
        ChannelSel = blanks(32)
        channelLoop = struct()
        DefaultConf = struct('R00', '0xDE',...
                             'R01', '0x42',...
                             'R02', '0x04',...
                             'R03', '0x00',...
                             'R04', '0xC0',...
                             'R05', '0x40',...
                             'R06', '0x80',...
                             'R07', '0x00',...
                             'R08', '0x16',...
                             'R09', '0x80',...
                             'R10', '0x17',...
                             'R11', '0x80',...
                             'R12', '0x2C',...
                             'R13', '0x86',...
                             'R14', '0xFF',...
                             'R15', '0xFF',...
                             'R16', '0x00',...
                             'R17', '0x00',...
                             'R18', '0xE900')
        All_btn
        None_btn
        Folder
        AllRecordStruct = struct()
        DataLoopsCnt = 0
        headercnt = 0
        TemporaryBinData = []
        first = true
        Lines = [];
        RTPlotStruct = struct()
    end
    
    methods (Access = private)
        
        function InitVariables(app)
            % Inicializas variables necesarias para el funcionamiento del
            % programa:
            %   - Cmd: Struct que contiene la info de cada comando en
            %   celdas.
            %   - Path to file: Carpeta donde se guardará el archivo
            %   - FileName : Nombre por defecto del archivo en cuestión. 
            %   - Deafult config: Configuración INTAN por defecto.
            app.Cmd.DirectCMD =  {"cmd", app.SelectDcmd.Value, num2str(app.Address.Value), dec2hex(app.Data.Value), num2str(app.Channel.Value)};
            app.Cmd.ChLoop = {'loop config', '-ch', Channels2Cmd(app) , '-blnk', num2str(app.Tblnk.Value) '-fs', num2str(app.Fs.Value), '-nest', ConvertStimuli(app), '-Tc', num2str(app.Tsave.Value)};
            app.Cmd.Init = {};
            app.PathtofileEditField.Value = pwd;
            app.FilenameEditField.Value = [datestr(datetime('now'),30) '_Capture.mat'];
            
            tmp = fieldnames(app.DefaultConf);
            for i = 1:length(tmp)-1
                app.(tmp{i}).Value = app.DefaultConf.(tmp{i});
            end

            app.AllRecordStruct.GeneralInfo.CaptureProgram.name = 'FPGA-Platform';
            app.AllRecordStruct.GeneralInfo.CaptureProgram.version = '0.5';
            app.AllRecordStruct.GeneralInfo.ConversionRate = 0.195;
            app.AllRecordStruct.GeneralInfo.Units = 'uV';
            app.AllRecordStruct.GeneralInfo.Exponent = -9;

        end

        function GenerateChannelsSel(app)
            % To-Do: Si al final esto se une con la otra app, estaría bien
            % bacano tener la huella del electrodo para poder seleccionar
            % los canales. 
            % 
            % Genera un selector de canales con checkbox. 

            uigridlayout(app.GridChannelsSelection,[2+1, 17]);
           
            for j = 1:32
                app.channelLoop.(['Ch_' num2str(j)]) =  uicheckbox(app.GridChannelsSelection, 'Visible','on');
                if j <= 16
                    app.channelLoop.(['Ch_' num2str(j)]).Layout.Row = 1;
                else
                    app.channelLoop.(['Ch_' num2str(j)]).Layout.Row = 2;
                end
                
                if j <=16
                    app.channelLoop.(['Ch_' num2str(j)]).Layout.Column = j;
                    app.channelLoop.(['Ch_' num2str(j)]).Value = true;
                    app.ChannelSel(1,j)= '1'; %Esto así para que luego lo podamos convertir a bin directamente (los MSB correponden con los canales menos significativos)
                else
                    app.channelLoop.(['Ch_' num2str(j)]).Layout.Column = j - 16;
                    app.channelLoop.(['Ch_' num2str(j)]).Value = false;
                    app.ChannelSel(1,j) = '0';
                end
                app.channelLoop.(['Ch_' num2str(j)]).Text = num2str(j);
                
                app.channelLoop.(['Ch_' num2str(j)]).ValueChangedFcn = @app.LoopChannelChange;
            end
                  
            app.All_btn = uibutton(app.GridChannelsSelection);
            app.All_btn.Text = 'Select all';
            app.All_btn.Layout.Row = 3;
            app.All_btn.Layout.Column = [3 8];
            app.All_btn.ButtonPushedFcn = @app.SelectAllChannels;

            app.None_btn = uibutton(app.GridChannelsSelection);
            app.None_btn.Text = 'Unselect all';
            app.None_btn.Layout.Row = 3;
            app.None_btn.Layout.Column = [9 14];
            app.None_btn.ButtonPushedFcn = @app.UnselectAllChannels;

            app.Cmd.ChLoop{3} = Channels2Cmd(app);
        end

        function LoopChannelChange(app, src, event)
            % Función callback cuando se desmarca un canal
            ch = str2double(src.Text);
            if app.channelLoop.(['Ch_' src.Text]).Value == true 
                app.ChannelSel(1, ch) = '1';
            else
                app.ChannelSel(1, ch) = '0';
            end
            app.Cmd.ChLoop{3} = Channels2Cmd(app);
            UpdateCmdText(app);
        end
        
        function SelectAllChannels(app, src, event)
            % Callback del botón selectAllChannels

            for i = 1:length(fieldnames(app.channelLoop))
                app.channelLoop.(['Ch_' num2str(i)]).Value = true;
            end
            app.ChannelSel = repmat('1', 1, 32);
            app.Cmd.ChLoop{3} = Channels2Cmd(app);
            UpdateCmdText(app);

        end
        
        function UnselectAllChannels(app, src, event)
            % To-Do: No parece funcionar
            %
            % Callback del botón unselectAllChannels

           for i = 1:length(fieldnames(app.channelLoop))
               app.channelLoop.(['Ch_' num2str(i)]).Value = false;
           end
           app.ChannelSel = repmat('0', 1, 32);
           UpdateCmdText(app);
        end

        function SearchPortsAndList(app)
            % Busca los puertos disponibles del serial port para poder
            % establecer la comunicación. Cuando los encuentra, actualiza
            % la lista de Control
            PortList = serialportlist("all");
            app.SelectControl.Items = {app.SelectControl.Items{:}, PortList{:}};
            app.SelectData.Items = {app.SelectData.Items{:}, PortList{:}};
%             for i = 1:length(PortList)
%                 app.SelectData.Items = PortList{i};
%                 app.SelectControl.Items(end +1) =  PortList{i};
%             end
        end
        
        function SerialConnect(app, Type)
            % Función que conecta ambos puertos serie.
            if Type == "Control"
                app.sControl = serialport(app.SelectControl.Value, app.BaudRateControl.Value);
                configureTerminator(app.sControl, "LF", "CR");
                %flush(app.sControl);
                configureCallback(app.sControl, "terminator", @app.ReceivedCmdCallback);
                app.ConsoleText.Value = "test";
                SendCmd(app)
                app.Lamp.Enable = "on";
            elseif Type == "Data"
                app.sData = serialport(app.SelectData.Value, app.BaudRateData.Value, "Parity","none","StopBits",1, "ByteOrder","big-endian","Timeout",10,"FlowControl","hardware");
                flush(app.sData)
                app.sData.UserData.Data = [];
                app.sData.UserData.Cnt = 0;
                app.sData.UserData.MaxSamplesToRead = (bin2dec(flip(app.ChannelSel))+1)*2*app.Fs.Value*app.Tsave.Value *app.TfullSample.Value * app.Fstim.Value;
                %Vamos a utilizar el userData para guardar y procesar la
                %info del callback en lugar de escribir en una variable
                %global. 
                configureCallback(app.sData, "byte", 2, @(varagin)app.ReceivedDataCallback);
                %Esto te indica cada cuantos bytes va a saltar la
                %interupción. 
                app.Lamp_2.Enable = "on";
            end 
        end
        
        function SerialDisconnect(app, Type)
            % Función que desconecta los puertos serie
            if Type == "Control"
                app.sControl = [];
                app.Lamp.Enable = "off";
            elseif Type == "Data"
                app.sData = [];
                app.Lamp_2.Enable = "off";
            end
        end
        
%         function ReadReceivedCMD(app, src)
%             while(app.sControl.NumBytesAvailable == 0)
%                 % Empty Loop
%             end
% 
%             while(app.sControl.NumBytesAvailable > 0)
%                 msg = readline(app.sControl);
%                 src.UserData = msg;
%                 if strcmp(app.CmdWindow.Value, '')
%                     plot = msg;
%                 else
%                     plot = [app.CmdWindow.Value(:)', msg];
%                 end
%                 app.CmdWindow.Value = plot;
%             end
%         end

        function ReceivedDataCallback(app,src,~)
            % La variable que tiene almacena elimina la cabecera.
            data = read(src, src.UserData.ChannelNumber+1, "int16");
            persistent sample;
            if isempty(sample)
                sample = 1;
            end
            tmp = find(data==26316);
            if length(tmp) == 1
                if tmp == 1
                   src.UserData.Data(sample, :) = data(2:end);
                else
                   src.UserData.Data(sample, :) = [data(tmp+1:end) zeros(1, tmp-1)];                 
                end
                UpdateRealTimePlot(app, src.UserData.Data(end,:), sample)
                sample = sample + 1;
            end
            %src.UserData.Data(sample, end+1) = data;
            src.UserData.Cnt = src.UserData.Cnt + src.UserData.ChannelNumber+ 1;
            if src.UserData.Cnt > src.UserData.MaxSamplesToRead || src.NumBytesAvailable == 0
                % Con esta condición se llega a cumplir siempre que el
                % los estímulos no estén los suficientemente separados como
                % para que el buffer se vaíe por completo
                % to-do: Añadir un sistema que permita contar el número de
                % estimulaciones y esperar hasta tentonces para poder
                % lanzarlo. 
                configureCallback(src, "off");
                app.Lamp_3.Enable = false;
                app.AllRecordStruct.(['Record_' num2str(app.DataLoopsCnt)]).RawData = ...
                    src.UserData.Data((app.AllRecordStruct.(['Record_' num2str(app.DataLoopsCnt)]).FPGACaptureInfo.Tsave * ...
                    app.AllRecordStruct.(['Record_' num2str(app.DataLoopsCnt)]).FPGACaptureInfo.SampleRate+1):end,:);
                %Quitamos las primeras muestras ya que es un evento que no
                %se graba correctamente. To-Do: Hacerlo directamente en la
                %FPGA.
            end
        end

        function ReceivedCmdCallback(app,src,~)
            % Esta función está configurada como callback cuando llega un
            % comando entero por el puerto habilitado para ello. Debeír
            % plotear en la ventana de texto lo que recibe. 
            %
            % To-Do: sustituir por html al igual que en la otra interfaz.
            %
            % Nota: Esto solo lee ahora después de un comando, se puede
            % hacer que leea siempre?
            msg = readline(src);
            src.UserData = msg;
            if strcmp(app.CmdWindow.Value, '')
                plot = msg{1};
            else
                plot = [app.CmdWindow.Value(:)', msg{1}];
            end
            app.CmdWindow.Value = plot;
        end
        

        function SendCmd(app)
            writeline(app.sControl, string(app.ConsoleText.Value))
%             writeline(app.sControl, app.ConsoleText.Value '   ']);
            %write(app.sControl, [app.ConsoleText.Value ' ' char(13)], "string")
            app.ConsoleText.Value = '';
            %ReadReceivedCMD(app)
        end
                        
        
        function UpdateCmdText(app)
            %Pasar de cell a cmd
            
            if strcmp(app.TabGroup.SelectedTab.Title, 'DirectCMD')
                app.ConsoleText.Value = app.Cmd.DirectCMD{1};
                switch app.SelectDcmd.Value
                    case 'read'
                        app.ConsoleText.Value = [app.ConsoleText.Value ' ' app.Cmd.DirectCMD{2}  ' ' app.Cmd.DirectCMD{3}];
                    case 'write'
                        app.ConsoleText.Value = [app.ConsoleText.Value ' ' app.Cmd.DirectCMD{2}  ' ' app.Cmd.DirectCMD{3} ' ' app.Cmd.DirectCMD{4}];
                    case {'clear', 'calibrate'}
                        app.ConsoleText.Value = [app.ConsoleText.Value ' ' app.Cmd.DirectCMD{2}];
                    case 'convert'
                        app.ConsoleText.Value = [app.ConsoleText.Value ' ' app.Cmd.DirectCMD{2} ' ' app.Cmd.DirectCMD{end}]; 
                end
            elseif strcmp(app.TabGroup.SelectedTab.Title, 'Ch Loop')
                app.ConsoleText.Value = [app.Cmd.ChLoop{1} ' ' app.Cmd.ChLoop{2} ' ' app.Cmd.ChLoop{3}];
                for i = 1:4
                    if app.(['CheckBox_'  num2str(i)]).Value == true
                        app.ConsoleText.Value = [app.ConsoleText.Value ' ' app.Cmd.ChLoop{2*i+2} ' ' app.Cmd.ChLoop{2*i + 3}];
                    end
                end
            end
        end
        
        
        function results = ConvertStimuli(app)
            %Convierte la frecuencia de stimuli en el número de muestras
            %por cada estimulo. 
            results = num2str(app.TfullSample.Value * app.Fstim.Value);
        end
        
        function results = Channels2Cmd(app)
            %Convierte los canales seleccionados en un número en binario
            %que se pueda pasar al comando. 

            results = ['0x' dec2hex(bin2dec(flip(app.ChannelSel)))];
        end
        
        function data = GenerateDataStruct(app)
            %Función encargada de generar la struct en la que se almacenan
            %cada loop de conversión. Esto se almacena luego en una struct
            %con todas.
            % To-Do: Migrar esto a una clase y generar objetos de esta
            % clase. 
            data = struct();
            data.RecordName = ['Record_' num2str(app.DataLoopsCnt)];
            data.RecordDate = datestr(datetime("now"));
            data.RecordedChannelsNumber = count(app.ChannelSel, '1');
            data.FPGACaptureInfo = GenerateCaptureInfo(app);

            data.RecordedChannelsIds = strfind(app.ChannelSel, '1'); %Esto nos genera un vector con las ids de los canales que estamos guardando. Asi no se guarda todo.
                % nota: Los canales así comienzan en 1, cuando en el
                % hardware a la hora de capturar empiezan en 0.
                % Para solucionar esto hay que restar 1
            data.ChannelsTimestamp = generateTimestamp(app);
                % nota: Generas en función de los parámetros el timesample,
                % lo que hace que lo sepas de antemano. La primera ronda de
                % canales
            data.UseTriggers = true;
            data.Triggers = generateTriggers(app);

            data.RawData = zeros(max(size(data.ChannelsTimestamp)), data.RecordedChannelsNumber, "int16");
            

        end

        function result = generateTriggers(app)
            %Genera una matriz con la posición de los triggers. Generamos
            %una matriz que almacena cada vez que empieza una estimulación
            %y se le resta el tiempo de blanking y el numero de muestras de
            %blnaking. 
            NStimPerCap = round(app.TfullSample.Value * app.Fstim.Value); % No. estimulaciones por captura
            Nstim = ceil(app.Fs.Value / app.Fstim.Value);        % No. muestras por estimulación
            result = [];
            Timecap = linspace(0, app.TfullSample.Value, NStimPerCap+1);
            for i = 1:NStimPerCap
                result = [result; Nstim*(i-1)   , Timecap(i)];
            end
        end
        
        function result = generateTimestamp(app)
            %Genera una matriz de 2 dimensiones que contiene el timestamp y
            %el número de sample que la contiene (esto no sería necesario
            %porque estan en los índices ¿?)
            result = [];
            result(:,1) = 0:1:(app.TfullSample.Value*app.Fs.Value);
            result(:,2) = 0:(1/app.Fs.Value):app.TfullSample.Value;
        end

%         function result = generateTriggers(app, timestamp)
%             %Genera un array de 2D  donde se almacena tanto el samplenumber
%             %(columna 1) como el timestamp (columna 2). Como en el
%             %timestamp se almacena la primera muestra de cada bucle, el
%             %trigger es el resultado de restarle al timstampe el tiempo de
%             %Blanking y el número de muestras durante el tiempo de
%             %blanking.
%             Nblnk = ceil(app.Tblnk.Value * app.Fs.Value);
% 
%             result(:,1) = timestamp(1,:) - Nblnk;
%             result(:,2) = timestamp(2,:) - app.Tblnk.Value;            
%         end
        
        
        function UpdateRealTimePlot(app, DataRow, sampleCnt)
            %Esto plotea en tiempo real, pero muy muy despacio, hay que
            %hacer que lo haga más rápido (limita por el uso de javascript
            %y html? Por otro lado igual se puede plotear solo un evento?
            %En verdad esto se actualiza a la velocidad que se entra en el
            %callback del serialport, ¿entonces esta es la velocidad a la
            %que se procesan los datos?
            
            %to-do: Modificar para que plotee uno de cada x datos, y así
            %aumentar la velocidad
            for i = 1:count(app.ChannelSel,'1')
                addpoints(app.Lines.(['ch_' num2str(i)]), app.RTPlotStruct.Time(sampleCnt), DataRow(i)) 
                drawnow limitrate
            end
        end
        
        function CreateRealTimeLines(app)
            %Inicializa los ejes para plotear en tiempo real y crea las
            %lineas de los canales.
            Nstim = round(app.TfullSample.Value * app.Fstim.Value)+2;
            app.RTPlotStruct.Time = 0:(1/app.Fs.Value):(app.Tsave.Value*1e-3*Nstim); %Ploteamos toto
            xlim(app.UIAxes, [0, app.RTPlotStruct.Time(end)])
            ylim(app.UIAxes, [-2.5e4, 2.5e4])
            legend(app.UIAxes)
            app.Lines = [];
            for i = 1:count(app.ChannelSel, '1')
                app.Lines.(['ch_' num2str(i)]) = animatedline(app.UIAxes, "DisplayName", ['ch' num2str(i)],"Color",[rand,rand,rand]);
            end
        end
        
        function InfoStruct = GenerateCaptureInfo(app)
            %Generamos una estructura que contiene la información sobre la
            %captura.
            InfoStruct = struct();
            InfoStruct.Fstim = app.Fstim.Value;
            InfoStruct.Tsave = app.Tsave.Value*1e-3;
            InfoStruct.Tblnk = app.Tblnk.Value*1e-6;
            InfoStruct.SampleRate = app.Fs.Value;
            InfoStruct.Trecording = app.TfullSample.Value;
        end
        
        function EliminateDC(app)
            app.AllRecordStruct.(['Record_' num2str(app.DataLoopsCnt)]).RawData = ...
                app.AllRecordStruct.(['Record_' num2str(app.DataLoopsCnt)]).RawData - ...
                mean(app.AllRecordStruct.(['Record_' num2str(app.DataLoopsCnt)]).RawData);
        end
        
        function CheckValidParamChLoop(app)
            %Comprueba tras cargar la configuración si los parametros
            %introducidos son válidos, si no lo son no te deja enviar el
            %start. 
            MaxWords = app.sData.BaudRate/(20*app.Fstim.Value);
            WordsGenerated = app.Fs.Value*(count(app.ChannelSel, '1')+1)*app.Tsave.Value*1e-3;
            if WordsGenerated > MaxWords
                warndlg('Los parámetros introducidos son incompatibles','Warning');
                app.StartcaptureButton.Enable = "off";
            else
                app.StartcaptureButton.Enable = "on";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            InitVariables(app);
            GenerateChannelsSel(app);
            SearchPortsAndList(app);

        end

        % Value changed function: ConnectData
        function ConnectDataValueChanged(app, event)
            value = app.ConnectData.Value;
            if value == true
                SerialConnect(app, "Data");
            else
                SerialDisconnect(app, "Data");
            end
        end

        % Value changed function: ConnectControl
        function ConnectControlValueChanged(app, event)
            value = app.ConnectControl.Value;
            if value == true
                SerialConnect(app, "Control");
            else
                SerialDisconnect(app, "Control");
            end
        end

        % Value changed function: SelectDcmd
        function SelectDcmdValueChanged(app, event)
            value = app.SelectDcmd.Value;
            switch value
                case 'read'
                    app.Address.Enable = "on";
                    app.Data.Enable = "off";
                    app.Channel.Enable = "off";
                case 'write'
                    app.Address.Enable = "on";
                    app.Data.Enable = "on";
                    app.Channel.Enable = "off";
                case 'convert'
                    app.Address.Enable = "off";
                    app.Data.Enable = "off";
                    app.Channel.Enable = "on";
                case {'calibrate', 'clear'}
                    app.Address.Enable = "off";
                    app.Data.Enable = "off";
                    app.Channel.Enable = "off";
            end
            app.Cmd.DirectCMD{2} = value;
            UpdateCmdText(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            UpdateCmdText(app);
        end

        % Value changed function: Address
        function AddressValueChanged(app, event)
            value = app.Address.Value;
            if(strcmp(app.SelectDcmd.Value, 'write'))
                if(value < 40 || (value > 44 && value < 60))
                    app.Cmd.DirectCMD{3} = num2str(value);
                else
                    fig = uifigure;
                    uialert(fig, ["Invalid write address"], "Waring", "Icon","warning");
                end
            elseif(strcmp(app.SelectDcmd.Value, 'read'))
                if(value > 63)
                    fig = uifigure;
                    uialert(fig, ["Invalid read address"], "Waring", "Icon","warning");
                else
                    app.Cmd.DirectCMD{3} = num2str(value);
                end
            end
            UpdateCmdText(app);
        end

        % Value changed function: Data
        function DataValueChanged(app, event)
            value = app.Data.Value;
            app.Cmd.DirectCMD{4} = dec2hex(value);
            UpdateCmdText(app);
        end

        % Value changed function: Channel
        function ChannelValueChanged(app, event)
            value = app.Channel.Value;
            app.Cmd.DirectCMD{5} = num2str(value);
            UpdateCmdText(app);
        end

        % Button pushed function: StartcaptureButton
        function StartcaptureButtonPushed(app, event)
            % To-Do: Hacer que si no se ha mandado la configuración, se
            % mande antes de lanzar el start.
            app.DataLoopsCnt = app.DataLoopsCnt +1;
            app.AllRecordStruct.(['Record_' num2str(app.DataLoopsCnt)]) = ...
                GenerateDataStruct(app);
            app.headercnt = 0;
            configureCallback(app.sData, "byte", 2, @app.ReceivedDataCallback);
            app.Lamp_3.Enable = true;
            app.sData.UserData.Data = [];
            app.sData.UserData.ChannelNumber = length(strfind(app.ChannelSel, '1'));
            app.sData.UserData.MaxSamplesToRead =  app.Tsave.Value*app.Fs.Value*app.TfullSample.Value*app.Fstim.Value*2*(count(app.ChannelSel, '1')+1);
            CreateRealTimeLines(app)                      
            flush(app.sData);
            writeline(app.sControl, 'loop start');
            app.ConsoleText.Value = '';       

        end

        % Value changed function: CheckBox_1, CheckBox_2, CheckBox_3, 
        % ...and 1 other component
        function CheckBox_1ValueChanged(app, event)
            UpdateCmdText(app);
        end

        % Value changed function: Tblnk
        function TblnkValueChanged(app, event)
            value = app.Tblnk.Value;
            app.Cmd.ChLoop{5} = num2str(value);
            UpdateCmdText(app);
        end

        % Value changed function: Fstim, TfullSample
        function FstimValueChanged(app, event)
            %value = app.Fstim.Value;
            app.Cmd.ChLoop{9} = ConvertStimuli(app);
            UpdateCmdText(app);
        end

        % Value changed function: Tsave
        function TsaveValueChanged(app, event)
            value = app.Tsave.Value;
            app.Cmd.ChLoop{11} = num2str(value);
            UpdateCmdText(app);
        end

        % Button pushed function: ChangepathButton
        function ChangepathButtonPushed(app, event)
           app.PathtofileEditField.Value = uigetdir();
        end

        % Button pushed function: SavetoFileButton
        function SavetoFileButtonPushed(app, event)
            % Carga los datos a un fichero .m para procesar guardarlos
            app.AllRecordStruct.GeneralInfo.FilePath = app.PathtofileEditField.Value;
            app.AllRecordStruct.GeneralInfo.FileName = app.FilenameEditField.Value;
            if(app.EliminateDCCheckBox.Value)
                EliminateDC(app)
            end
            saveVar = app.AllRecordStruct;
            save([app.PathtofileEditField.Value '\' app.FilenameEditField.Value], "saveVar");
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            SendCmd(app);
        end

        % Button pushed function: LoadConfigurationButton, SendDcmd
        function SendCMDButtonPushed(app, event)
            UpdateCmdText(app);
            CheckValidParamChLoop(app);
            SendCmd(app);
        end

        % Button pushed function: DefaultConfigButton
        function DefaultConfigButtonPushed(app, event)
            tmp = fieldnames(app.DefaultConf);
            for i = 1:length(tmp)-1
                app.(tmp{i}).Value = app.DefaultConf.(tmp{i});
            end
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            tmp = fieldnames(app.DefaultConf);
            app.ConsoleText.Value = 'init conf';
            
            for i = 1:length(tmp)-1
                app.ConsoleText.Value = [app.ConsoleText.Value ' ' '-' tmp{i} ' ' app.DefaultConf.(tmp{i})];
            end 
            %No llamamos al UpdateCmd, lo actulizamos directamente ya que
            %la inicialización no se lanzará tan de seguido, no hace falta
            %guardar la última versión del cmd lanzado. 
            SendCmd(app);
        end

        % Value changed function: Fs
        function FsValueChanged(app, event)
            value = app.Fs.Value;
            app.Cmd.ChLoop{7} = num2str(value);
            UpdateCmdText(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'MATLAB App';
            app.MainGrid = uigridlayout(app.MainFigure);
            app.CmdPanel = uipanel(app.MainGrid);
            app.CmdPanel.Title = 'Commands';
            app.TabGroup = uitabgroup(app.CmdPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DirectCMDTab = uitab(app.TabGroup);
            app.DirectCMDTab.Title = 'DirectCMD';
            app.GridLayout4 = uigridlayout(app.DirectCMDTab);
            app.SelectDcmd = uilistbox(app.GridLayout4);
            app.SelectDcmd.Items = {'Read', 'Write', 'Convert', 'Calibrate', 'Clear calibration'};
            app.SelectDcmd.ItemsData = {'read', 'write', 'convert', 'calibrate', 'clear'};
            app.SelectDcmd.ValueChangedFcn = createCallbackFcn(app, @SelectDcmdValueChanged, true);
            app.SelectDcmd.Value = 'read';
            app.AddressLabel = uilabel(app.GridLayout4);
            app.AddressLabel.Text = 'Address';
            app.Address = uieditfield(app.GridLayout4, 'numeric');
            app.Address.ValueChangedFcn = createCallbackFcn(app, @AddressValueChanged, true);
            app.DatatoWritehexLabel = uilabel(app.GridLayout4);
            app.DatatoWritehexLabel.Text = 'Data to Write';
            app.Data = uieditfield(app.GridLayout4, 'numeric');
            app.Data.ValueChangedFcn = createCallbackFcn(app, @DataValueChanged, true);
            app.Data.Enable = 'off';
            app.SendDcmd = uibutton(app.GridLayout4, 'push');
            app.SendDcmd.ButtonPushedFcn = createCallbackFcn(app, @SendCMDButtonPushed, true);
            app.SendDcmd.Text = 'Send';
            app.ChannelLabel = uilabel(app.GridLayout4);
            app.ChannelLabel.Text = 'Channel';
            app.Channel = uieditfield(app.GridLayout4, 'numeric');
            app.Channel.ValueChangedFcn = createCallbackFcn(app, @ChannelValueChanged, true);
            app.Channel.Enable = 'off';
            app.IniTab = uitab(app.TabGroup);
            app.IniTab.Title = 'Ini';
            app.GridLayout6 = uigridlayout(app.IniTab);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.DefaultConfigButton = uibutton(app.GridLayout7, 'push');
            app.DefaultConfigButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultConfigButtonPushed, true);
            app.DefaultConfigButton.Text = 'Default Config';
            app.LoadConfigButton = uibutton(app.GridLayout7, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.ADCConfigurationandAmplifierFastSettleLabel = uilabel(app.GridLayout6);
            app.ADCConfigurationandAmplifierFastSettleLabel.Text = 'ADC Configuration and Amplifier Fast Settle';
            app.R00 = uieditfield(app.GridLayout6, 'text');
            app.SupplySensorandADCBufferBiasCurrentLabel = uilabel(app.GridLayout6);
            app.SupplySensorandADCBufferBiasCurrentLabel.Text = 'Supply Sensor and ADC Buffer Bias Current';
            app.R01 = uieditfield(app.GridLayout6, 'text');
            app.MUXBiasCurrentLabel = uilabel(app.GridLayout6);
            app.MUXBiasCurrentLabel.Text = 'MUX Bias Current';
            app.R02 = uieditfield(app.GridLayout6, 'text');
            app.MUXLoadTemperatureSensorandAuxiliaryDigitalOutputLabel = uilabel(app.GridLayout6);
            app.MUXLoadTemperatureSensorandAuxiliaryDigitalOutputLabel.Text = 'MUX Load Temperature Sensor, and Auxiliary Digital Output';
            app.R03 = uieditfield(app.GridLayout6, 'text');
            app.ADCOutputyFormatandDSPOffsetRemovalLabel = uilabel(app.GridLayout6);
            app.ADCOutputyFormatandDSPOffsetRemovalLabel.Text = 'ADC Outputy Format and DSP Offset Removal';
            app.R04 = uieditfield(app.GridLayout6, 'text');
            app.ImpedanceCheckControlLabel = uilabel(app.GridLayout6);
            app.ImpedanceCheckControlLabel.Text = 'Impedance Check Control';
            app.R05 = uieditfield(app.GridLayout6, 'text');
            app.ImpedanceCheckDACLabel = uilabel(app.GridLayout6);
            app.ImpedanceCheckDACLabel.Text = 'Impedance Check DAC';
            app.R06 = uieditfield(app.GridLayout6, 'text');
            app.ImpedanceCheckAmplifierSelectLabel = uilabel(app.GridLayout6);
            app.ImpedanceCheckAmplifierSelectLabel.Text = 'Impedance Check Amplifier Select';
            app.R07 = uieditfield(app.GridLayout6, 'text');
            app.OnChipAmplifierBandwdthSelectR08Label = uilabel(app.GridLayout6);
            app.OnChipAmplifierBandwdthSelectR08Label.Text = 'On-Chip Amplifier Bandwdth Select (R08)';
            app.R08 = uieditfield(app.GridLayout6, 'text');
            app.OnChipAmplifierBandwdthSelectR09Label = uilabel(app.GridLayout6);
            app.OnChipAmplifierBandwdthSelectR09Label.Text = 'On-Chip Amplifier Bandwdth Select (R09)';
            app.R09 = uieditfield(app.GridLayout6, 'text');
            app.OnChipAmplifierBandwdthSelectR10Label = uilabel(app.GridLayout6);
            app.OnChipAmplifierBandwdthSelectR10Label.Text = 'On-Chip Amplifier Bandwdth Select (R10)';
            app.R10 = uieditfield(app.GridLayout6, 'text');
            app.OnChipAmplifierBandwdthSelectR11Label = uilabel(app.GridLayout6);
            app.OnChipAmplifierBandwdthSelectR11Label.Text = 'On-Chip Amplifier Bandwdth Select (R11)';
            app.R11 = uieditfield(app.GridLayout6, 'text');
            app.OnChipAmplifierBandwdthSelectR12Label = uilabel(app.GridLayout6);
            app.OnChipAmplifierBandwdthSelectR12Label.Text = 'On-Chip Amplifier Bandwdth Select (R12)';
            app.R12 = uieditfield(app.GridLayout6, 'text');
            app.OnChipAmplifierBandwdthSelectR13Label = uilabel(app.GridLayout6);
            app.OnChipAmplifierBandwdthSelectR13Label.Text = 'On-Chip Amplifier Bandwdth Select (R13)';
            app.R13 = uieditfield(app.GridLayout6, 'text');
            app.IndividualAmplifierPoweR14Label = uilabel(app.GridLayout6);
            app.IndividualAmplifierPoweR14Label.Text = 'Individual Amplifier Power (R14)';
            app.R14 = uieditfield(app.GridLayout6, 'text');
            app.IndividualAmplifierPowerR14Label = uilabel(app.GridLayout6);
            app.IndividualAmplifierPowerR14Label.Text = 'Individual Amplifier Power (R15)';
            app.R15 = uieditfield(app.GridLayout6, 'text');
            app.IndividualAmplifierPowerR14Label_2 = uilabel(app.GridLayout6);
            app.IndividualAmplifierPowerR14Label_2.Text = 'Individual Amplifier Power (R16)';
            app.R16 = uieditfield(app.GridLayout6, 'text');
            app.IndividualAmplifierPowerR17Label = uilabel(app.GridLayout6);
            app.IndividualAmplifierPowerR17Label.Text = 'Individual Amplifier Power (R17)';
            app.R17 = uieditfield(app.GridLayout6, 'text');
            app.ChLoopTab = uitab(app.TabGroup);
            app.ChLoopTab.Title = 'Ch Loop';
            app.ChLoopGrid = uigridlayout(app.ChLoopTab);
            app.PeriodoblankingmusLabel = uilabel(app.ChLoopGrid);
            app.PeriodoblankingmusLabel.Text = 'Periodo blanking (us)';
            app.Tblnk = uieditfield(app.ChLoopGrid, 'numeric');
            app.Tblnk.ValueChangedFcn = createCallbackFcn(app, @TblnkValueChanged, true);
            app.Tblnk.Value = 25;
            app.FrecuenciademuestreoHzEditFieldLabel = uilabel(app.ChLoopGrid);
            app.FrecuenciademuestreoHzEditFieldLabel.Text = 'Frecuencia de muestreo (Hz)';
            app.Fs = uieditfield(app.ChLoopGrid, 'numeric');
            app.Fs.ValueChangedFcn = createCallbackFcn(app, @FsValueChanged, true);
            app.Fs.Value = 7500;
            app.PeriododecapturaporestimulacinmusLabel = uilabel(app.ChLoopGrid);
            app.PeriododecapturaporestimulacinmusLabel.Text = 'T captura por estim (ms)';
            app.Tsave = uieditfield(app.ChLoopGrid, 'numeric');
            app.Tsave.ValueChangedFcn = createCallbackFcn(app, @TsaveValueChanged, true);
            app.Tsave.Value = 400;
            app.LoadConfigurationButton = uibutton(app.ChLoopGrid, 'push');
            app.LoadConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SendCMDButtonPushed, true);
            app.LoadConfigurationButton.Text = 'Load Configuration';
            app.ChannelSelectorPanel = uipanel(app.ChLoopGrid);
            app.ChannelSelectorPanel.Title = 'Channel Selector';
            app.GridChannelsSelection = uigridlayout(app.ChannelSelectorPanel);
            app.CheckBox_3 = uicheckbox(app.ChLoopGrid);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.ChLoopGrid);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.CheckBox_4.Value = true;
            app.DuracinmximadelamuestrasEditFieldLabel = uilabel(app.ChLoopGrid);
            app.DuracinmximadelamuestrasEditFieldLabel.Text = 'Duración máxima de la muestra (s)';
            app.TfullSample = uieditfield(app.ChLoopGrid, 'numeric');
            app.TfullSample.ValueChangedFcn = createCallbackFcn(app, @FstimValueChanged, true);
            app.TfullSample.Value = 10;
            app.StartcaptureButton = uibutton(app.ChLoopGrid, 'push');
            app.StartcaptureButton.ButtonPushedFcn = createCallbackFcn(app, @StartcaptureButtonPushed, true);
            app.StartcaptureButton.Text = 'Start capture';
            app.CheckBox_1 = uicheckbox(app.ChLoopGrid);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Text = '';
            app.CheckBox_1.Value = true;
            app.CheckBox_2 = uicheckbox(app.ChLoopGrid);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.Lamp_3 = uilamp(app.ChLoopGrid);
            app.Lamp_3.Enable = 'off';
            app.Fstim = uieditfield(app.ChLoopGrid, 'numeric');
            app.Fstim.ValueChangedFcn = createCallbackFcn(app, @FstimValueChanged, true);
            app.Fstim.Value = 1;
            app.FrecuenciaestimulacinHzLabel = uilabel(app.ChLoopGrid);
            app.FrecuenciaestimulacinHzLabel.Text = 'Frecuencia estimulación (Hz)';
            app.LoopPlotTab = uitab(app.TabGroup);
            app.LoopPlotTab.Title = 'Loop Plot';
            app.RTGrid = uigridlayout(app.LoopPlotTab);
            app.UIAxes = uiaxes(app.RTGrid);
            title(app.UIAxes, 'Real time Plot')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Integer')
            zlabel(app.UIAxes, 'Z')
            app.FileConfigTab = uitab(app.TabGroup);
            app.FileConfigTab.Title = 'File Config.';
            app.GridLayout5 = uigridlayout(app.FileConfigTab);
            app.PathtofileEditFieldLabel = uilabel(app.GridLayout5);
            app.PathtofileEditFieldLabel.Text = 'Path to file';
            app.PathtofileEditField = uieditfield(app.GridLayout5, 'text');
            app.PathtofileEditField.Editable = 'off';
            app.ChangepathButton = uibutton(app.GridLayout5, 'push');
            app.ChangepathButton.ButtonPushedFcn = createCallbackFcn(app, @ChangepathButtonPushed, true);
            app.ChangepathButton.Text = 'Change path';
            app.FilenameEditFieldLabel = uilabel(app.GridLayout5);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.GridLayout5, 'text');
            app.SavetoFileButton = uibutton(app.GridLayout5, 'push');
            app.SavetoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoFileButtonPushed, true);
            app.SavetoFileButton.Text = 'Save to File';
            app.EliminateDCCheckBox = uicheckbox(app.GridLayout5);
            app.EliminateDCCheckBox.Text = 'Eliminate DC';
            app.EliminateDCCheckBox.Value = true;
            app.ComPanel = uipanel(app.MainGrid);
            app.ComPanel.Title = 'Connections';
            app.GridLayout3 = uigridlayout(app.ComPanel);
            app.ControlCom = uipanel(app.GridLayout3);
            app.ControlCom.Title = 'Control';
            app.ControlGrid = uigridlayout(app.ControlCom);
            app.SelectControl = uidropdown(app.ControlGrid);
            app.SelectControl.Items = {'-'};
            app.SelectControl.Value = '-';
            app.BaudRateEditFieldLabel = uilabel(app.ControlGrid);
            app.BaudRateEditFieldLabel.Text = 'BaudRate';
            app.BaudRateControl = uieditfield(app.ControlGrid, 'numeric');
            app.BaudRateControl.Value = 115200;
            app.ConnectControl = uibutton(app.ControlGrid, 'state');
            app.ConnectControl.ValueChangedFcn = createCallbackFcn(app, @ConnectControlValueChanged, true);
            app.ConnectControl.Text = 'Connect';
            app.Lamp = uilamp(app.ControlGrid);
            app.Lamp.Enable = 'off';
            app.DataCom = uipanel(app.GridLayout3);
            app.DataCom.Title = 'Data';
            app.DataGrid = uigridlayout(app.DataCom);
            app.SelectData = uidropdown(app.DataGrid);
            app.SelectData.Items = {'-'};
            app.SelectData.Value = '-';
            app.BaudRateLabel = uilabel(app.DataGrid);
            app.BaudRateLabel.Text = 'BaudRate';
            app.BaudRateData = uieditfield(app.DataGrid, 'numeric');
            app.BaudRateData.Value = 1000000;
            app.ConnectData = uibutton(app.DataGrid, 'state');
            app.ConnectData.ValueChangedFcn = createCallbackFcn(app, @ConnectDataValueChanged, true);
            app.ConnectData.Text = 'Connect';
            app.Lamp_2 = uilamp(app.DataGrid);
            app.Lamp_2.Enable = 'off';
            app.ConsolePanel = uipanel(app.MainGrid);
            app.ConsolePanel.Title = 'Control Command View';
            app.ConsoleGrid = uigridlayout(app.ConsolePanel);
            app.ConsoleText = uieditfield(app.ConsoleGrid, 'text');
            app.ConsoleText.Value = ' ';
            app.SendButton = uibutton(app.ConsoleGrid, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.CmdWindow = uitextarea(app.ConsoleGrid);
            app.CmdWindow.Editable = 'off';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppCmd_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end