classdef AppGui_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        ImportOpenEphysFilesMenu  matlab.ui.container.Menu
        ImportMCSFilesMenu        matlab.ui.container.Menu
        ImportPlatformFilesMenu   matlab.ui.container.Menu
        ExportDataTomatMenu       matlab.ui.container.Menu
        PlotMenu                  matlab.ui.container.Menu
        OpenNewPlotPlaneMenu      matlab.ui.container.Menu
        ClosePlotPlanesMenu       matlab.ui.container.Menu
        OptionsMenu               matlab.ui.container.Menu
        SpliteventsusingexternaltriggersMenu  matlab.ui.container.Menu
        SplitSelectedChannel      matlab.ui.container.Menu
        SplitAllChannels          matlab.ui.container.Menu
        SpliteventsNT             matlab.ui.container.Menu
        SplitSelectedChannelNT    matlab.ui.container.Menu
        SplitAllChannelsNT        matlab.ui.container.Menu
        ChangeParametersMenu      matlab.ui.container.Menu
        CalculateallchannelsEventsmeanMenu  matlab.ui.container.Menu
        FeatureExtractorMenu      matlab.ui.container.Menu
        LoadMEAFootPrint          matlab.ui.container.Menu
        GridWindow                matlab.ui.container.GridLayout
        HTML                      matlab.ui.control.HTML
        PanelMultihannel          matlab.ui.container.Panel
        GridMultiChannel          matlab.ui.container.GridLayout
        SelectChannelEvent        matlab.ui.control.NumericEditField
        SelecteventtoplotEditFieldLabel  matlab.ui.control.Label
        SingleChannelPlotPanel    matlab.ui.container.Panel
        GridSingleChannel         matlab.ui.container.GridLayout
        ShowchFeaturesButton      matlab.ui.control.Button
        ShowchmeanButton          matlab.ui.control.Button
        NOEInfoLabel              matlab.ui.control.Label
        NOELabel                  matlab.ui.control.Label
        UpdateEventsPlotButton    matlab.ui.control.Button
        UpdateChannelPlotButton   matlab.ui.control.Button
        EditField                 matlab.ui.control.NumericEditField
        NumberOfEventsLabel       matlab.ui.control.Label
        CurrentChannelLabel       matlab.ui.control.Label
        RecordInformationPanel    matlab.ui.container.Panel
        GridRecordInformation     matlab.ui.container.GridLayout
        TriggersInfoLabel         matlab.ui.control.Label
        ExternalTriggersLabel     matlab.ui.control.Label
        DurationInfoLabel         matlab.ui.control.Label
        DurationLabel             matlab.ui.control.Label
        PathInfoLabel             matlab.ui.control.Label
        SampleInfoLabel           matlab.ui.control.Label
        SampleRateLabel           matlab.ui.control.Label
        FileInfoLabel             matlab.ui.control.Label
        ProgramInfoLabel          matlab.ui.control.Label
        FilePathLabel             matlab.ui.control.Label
        ProgramLabel              matlab.ui.control.Label
        ElectrodeInfoLabel        matlab.ui.control.Label
        RecordElectrodeLabel      matlab.ui.control.Label
        RecordPathLabel           matlab.ui.control.Label
        NameInfoLabel             matlab.ui.control.Label
        RecordNameLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        DialogApp
        ChangeParamsSIEApp
        PlotPlanes = struct()
        PlotPlanes_2

        SplitEventsParam = struct('Threshold', 50, 'Prominence', 20, 'StartPos', 0)
        Folder
        SelectedRecord
        EnableTrigger = false

        PanelEventsSelection       matlab.ui.container.Panel
        PanelChannelsSelection     matlab.ui.container.Panel
        PanelECSelection           matlab.ui.container.Panel
        GridEventSelection         matlab.ui.container.GridLayout
        GridChannelsSelection      matlab.ui.container.GridLayout
        GrieECSelection            matlab.ui.container.GridLayout
        
        EventsCheckboxStruct = struct()
        ChannelsCheckboxStruct = struct()
        
        ElectrodeType = "";         % Tipo de electrodo que estamos usando para capturar (realmente el no máximo de canales a capturar (32 o 64)
        EventToPlot = 3             % Evento a plotear
        IsEventsVisible = [];       % Array que indica los eventos que son visibles
        IsChannelVisible = [];      % Matriz que indica qué canales son visibles

        PlotMean                    % Variable que indica si la media está ploteada
        Log
    end
    
    properties (Access = public)
        SelectedChannel = 22         % Canal selecionado para los singleplot
        SplitEventsData = struct()  % Struct donde se almacena cada Evento en un campo + 2 campos más. 
        MeanStruct = struct()       % Estructura de la media de los eventos (To-Do: En un futuro implementar dentro del SplitInEvents2)
        FeaturesStruct = struct()   % Estructura que almacena las características de todos los canales. (To-Do: En un futuro debería ir con los SplitEvents)
        ElectrodeFootprint = [];    %matriz que almacena la huella del electrodo
        Data = LabData              % Variable que almacena todos los datos de las señales
    end
    methods (Access = private)

        function result = DoSplit(app, channel)
            %Funcion que se encarga de decidir si que SplitInEvents se
            %hace.
            if GetTriggerInfo(app.Data) == true
                result = SplitInEvents2(app.Data, channel);
                result.Event_mean = [];
                result = orderfields(result);
            else
                result = SplitInEvents2(app.Data, channel, app.SplitEventsParam);
                result.Event_mean = [];
                result = orderfields(result);
            end
        end
        
        function ShowRecordingInfo(app)
            %ShowRecordingInfo muestra la info del Record seleccionado.
            app.NameInfoLabel.Text = GetRecordName(app.Data);
            app.ElectrodeInfoLabel.Text = int2str(GetChannelsNumber(app.Data));
            app.PathInfoLabel.Text = GetRecordPath(app.Data);

            app.FileInfoLabel.Text = GetFilePath(app.Data);
            app.SampleInfoLabel.Text = int2str(GetSampleRate(app.Data));
            program = GetProgramInfo(app.Data);
            app.ProgramInfoLabel.Text = join([program.name convertCharsToStrings(program.version)]);

            tmp = GetChannelTimestamp(app.Data);         
            app.DurationInfoLabel.Text = [int2str(tmp(end)) ' secs'];

            if GetTriggerInfo(app.Data) == true
                app.TriggersInfoLabel.Text = 'true';
            elseif GetTriggerInfo(app.Data) == false
                app.TriggersInfoLabel.Text = 'false';
            end
            
            app.NameInfoLabel.Visible = 'on';
            app.ElectrodeInfoLabel.Visible = 'on';
            app.PathInfoLabel.Visible = 'on';
            app.FileInfoLabel.Visible = 'on';
            app.SampleInfoLabel.Visible = 'on';
            app.ProgramInfoLabel.Visible = 'on';
            app.DurationInfoLabel.Visible = 'on';
            app.TriggersInfoLabel.Visible = 'on';
        end
        
        function UpdateElectrodeInfo(app)
            %UpdateElectrodeInfo Actualiza el número de eventos que hay en
            %la muestra del electrodo seleccionado.

            app.SplitEventsData.(['Channel_' int2str(app.SelectedChannel)]) = DoSplit(app, app.SelectedChannel);

            tmp = GetTrigerSampleNumber(app.Data);
            tmp = length(tmp(1:2:end));
            if tmp == 0
                tmp = length(fieldnames(app.SplitEventsData.(['Channel_' int2str(app.SelectedChannel)]))) - 3;
            end

            app.NOEInfoLabel.Text = int2str(tmp);

            app.NOEInfoLabel.Visible = "on";
        end  
        
        function GenerateMultiChannelSelector(app)
            %GenerateMultiChannelSelector genera la cuadricula que permite
            %selecionar los canales.
            app.PanelChannelsSelection = uipanel(app.GridMultiChannel);
            app.PanelChannelsSelection.Title = 'Channels Selection';
            app.PanelChannelsSelection.TitlePosition = "centertop";
            app.PanelChannelsSelection.Layout.Row = [1 4];
            app.PanelChannelsSelection.Layout.Column = [1 2];

            app.GridChannelsSelection = uigridlayout(app.PanelChannelsSelection, ...
                [size(app.ElectrodeFootprint, 1)+1, size(app.ElectrodeFootprint, 2)]);
            cnt = 1;
            for j = 1:size(app.ElectrodeFootprint, 1)
                for i = 1:size(app.ElectrodeFootprint, 2)
                    pos = [i j];
                    if (app.ElectrodeFootprint(i,j) == 1 && ...
                        cnt <= GetChannelsNumber(app.Data) && ...
                        isempty(find(GetChannelsID(app.Data)==str2double(sprintf("%d%d", i, j)),1))==false)

                        app.ChannelsCheckboxStruct.(['CheckboxChannelSel_' int2str(i) int2str(j)]) = ...
                            uicheckbox(app.GridChannelsSelection, 'Visible','on');
                        app.ChannelsCheckboxStruct.(['CheckboxChannelSel_' int2str(i) int2str(j)]).Layout.Row = j;
                        app.ChannelsCheckboxStruct.(['CheckboxChannelSel_' int2str(i) int2str(j)]).Layout.Column = i;
                        app.ChannelsCheckboxStruct.(['CheckboxChannelSel_' int2str(i) int2str(j)]).Text = [int2str(i) int2str(j)];
                        app.ChannelsCheckboxStruct.(['CheckboxChannelSel_' int2str(i) int2str(j)]).Value = false;
                        app.ChannelsCheckboxStruct.(['CheckboxChannelSel_' int2str(i) int2str(j)]).ValueChangedFcn = ...
                            @app.ShowSelectedChannel;
                        cnt = cnt + 1; 
                    elseif ~(isequal(pos,[1 1]) || isequal(pos,size(app.ElectrodeFootprint)) || isequal(pos,[1 size(app.ElectrodeFootprint,1)]) || isequal(pos, [size(app.ElectrodeFootprint,2) 1]))
                        app.ChannelsCheckboxStruct.(['CheckboxChannelSel_' int2str(i) int2str(j)]) = ...
                            uicheckbox(app.GridChannelsSelection, 'Visible','on', 'Enable','off', Text="");
                        app.ChannelsCheckboxStruct.(['CheckboxChannelSel_' int2str(i) int2str(j)]).Layout.Row = j;
                        app.ChannelsCheckboxStruct.(['CheckboxChannelSel_' int2str(i) int2str(j)]).Layout.Column = i;
                        app.ElectrodeFootprint(i,j) = 0;
                    end
                end
            end
            
            app.ChannelsCheckboxStruct.All_btn = uibutton(app.GridChannelsSelection);
            app.ChannelsCheckboxStruct.All_btn.Text = 'Select all';
            app.ChannelsCheckboxStruct.All_btn.Layout.Row = size(app.ElectrodeFootprint, 1) +1;
            app.ChannelsCheckboxStruct.All_btn.Layout.Column = [1 4];
            app.ChannelsCheckboxStruct.All_btn.ButtonPushedFcn = @app.SelectAllChannels;

            app.ChannelsCheckboxStruct.None_btn = uibutton(app.GridChannelsSelection);
            app.ChannelsCheckboxStruct.None_btn.Text = 'Unselect all';
            app.ChannelsCheckboxStruct.None_btn.Layout.Row = size(app.ElectrodeFootprint, 1) +1;
            app.ChannelsCheckboxStruct.None_btn.Layout.Column = [5 8];
            app.ChannelsCheckboxStruct.None_btn.ButtonPushedFcn = @app.UnselectAllChannels;

            app.SelecteventtoplotEditFieldLabel.Visible = "on";
            app.SelectChannelEvent.Value = app.EventToPlot;
            app.SelectChannelEvent.Visible = "on";
        end


        function SelectAllChannels(app, src, event)
            % Callback function del botón seleccionar todos los canales.
            % Selecciona todos los canales y los plotea
            for j = 1:size(app.ElectrodeFootprint, 1)
                for i = 1:size(app.ElectrodeFootprint, 2)
                    if(app.ElectrodeFootprint(i, j) == 1)
                        app.ChannelsCheckboxStruct.(['CheckboxChannelSel_' int2str(i) int2str(j)]).Value = true;
                        app.IsChannelVisible(i,j) = true;
                        if(app.EventToPlot) == 0
%                             PlotChannel(app.PlotPlanes.MultiPlane, ['Channel_' int2str(i) int2str(j)],...
%                                 Get1DChannelID(app.Data, str2double([int2str(i) int2str(j)])),...
%                                 app.SplitEventsData.(['Channel_' int2str(i) int2str(j)]).(['Event_' int2str(app.EventToPlot)]),...
%                                 app.SplitEventsData.Timestamp)

                        else
%                             PlotChannel(app.PlotPlanes.MultiPlane, ['Channel_' int2str(i) int2str(j)],...
%                                 Get1DChannelID(app.Data, str2double([int2str(i) int2str(j)])),...
%                                 app.SplitEventsData.(['Channel_' int2str(i) int2str(j)]).(['Event_' int2str(app.EventToPlot)]),...
%                                 app.SplitEventsData.(['Channel_' int2str(i) int2str(j)]).Timestamp);
                            PlotChannel(app.PlotPlanes_2, ['Channel_' int2str(i) int2str(j)],...
                                Get1DChannelID(app.Data, str2double([int2str(i) int2str(j)])),...
                                app.SplitEventsData.(['Channel_' int2str(i) int2str(j)]).(['Event_' num2str(app.EventToPlot,"%02d")]),...
                                app.SplitEventsData.(['Channel_' int2str(i) int2str(j)]).Timestamp);
                        end
                    end
                end
            end
            
        end

        function UnselectAllChannels(app, src, event)
            %Callback function del botón que quita todos los canales.
            for j = 1:size(app.ElectrodeFootprint, 1)
                for i = 1:size(app.ElectrodeFootprint, 2)
                    if(app.ElectrodeFootprint(i, j) == 1)
                        app.ChannelsCheckboxStruct.(['CheckboxChannelSel_' int2str(i) int2str(j)]).Value = false;
                        app.IsChannelVisible(i,j) = false;
%                         DispHideChannel(app.PlotPlanes.MultiPlane, Get1DChannelID(app.Data, str2double([int2str(i) int2str(j)])), false);
                        DispHideChannel(app.PlotPlanes_2, Get1DChannelID(app.Data, str2double([int2str(i) int2str(j)])), false);

                    end
                end
            end
        end
        
%         function ShowEventsSelection(app)
%             %ShowEventsSelection se encarga de generar la matriz de
%             %checkbox para seleccionar los evento s a utilizar. 
% 
%             app.PanelEventsSelection = uipanel(app.GridSingleChannel);
%             app.PanelEventsSelection.Title = 'Event Selection';
%             app.PanelEventsSelection.TitlePosition = "centertop";
%             app.PanelEventsSelection.Layout.Row = 5;
%             app.PanelEventsSelection.Layout.Column = [1 3];
% 
%             %Creamos un nuevo Grid para almacenar la selección de eventos.
%             try
%             tmp =length(fieldnames(app.SplitEventsData.(['Channel_' int2str(app.SelectedChannel)]))) -2;
%             catch
%                 if GetTriggersInfo(app.Data) == true
%                     SplitInEvents2(app.Data, app.SelectedChannel)
%                 else
%                     SplitInEvents2(app.Data, app.SelectedChannel, app.SplitEventsParam);
%                 end
% 
%                 tmp =length(fieldnames(app.SplitEventsData.(['Channel_' int2str(app.SelectedChannel)]))) -2;
%             end
%             app.GridEventSelection = uigridlayout(app.PanelEventsSelection, ...
%                 [ceil(tmp/4) 4]); 
%             
%             for i = 1:tmp
%                 app.EventsCheckboxStruct.(['CheckboxEventSel_' int2str(i)]) = ...
%                     uicheckbox(app.GridEventSelection);
%                 app.EventsCheckboxStruct.(['CheckboxEventSel_' int2str(i)]).Text = int2str(i);
%                 app.EventsCheckboxStruct.(['CheckboxEventSel_' int2str(i)]).Value = true;
%                 app.EventsCheckboxStruct.(['CheckboxEventSel_' int2str(i)]).ValueChangedFcn = @app.ShowSelectedEvent;
%                 %ShowSelectedEvent(app, int2str(i), false)
%             end
% 
%             app.IsEventsVisible = true(1,tmp);
% 
%             app.PlotMean = uicheckbox(app.GridEventSelection);
%             app.PlotMean.Text = "Mean";
%             app.PlotMean.Value = false;
%             app.PlotMean.ValueChangedFcn = @app.showOneChMean;
%             app.PlotMean.Enable = false;
%         end
%         
%         function ShowSelectedEvent(app, src, event)
%             %ShowSelectedEvent se encarga de llamar a plotplane y lanzar
%             %los cambios en el plotPlane. 
%             % To-Do: Modificar aquí el vector de eventos
% 
%             DispHideEvent(app.PlotPlanes.EventsPlane, str2double(src.Text), event.Value);
%             
%         end
        
        
        function ShowSelectedChannel(app, src, event)
            % Callback de cuando se selecciona el canal para plotear en el
            % multiplane. Salta en la checkbox del canal.
            DispHideChannel(app.PlotPlanes_2, Get1DChannelID(app.Data, str2double(src.Text)), event.Value);     
        end
        
        
        function showOneChMean(app, src, event)
            % Callback que salta cuando se muestra la media del canal.
            DispHideEventsMean(app.PlotPlanes.EventsPlane, event.Value);
        end
        
        
        function result =  CalculateChannelMean(app, channel)
            tmp = fieldnames(app.SplitEventsData.(['Channel_' int2str(channel)]));
            SelectedEventsMatrix = zeros(length(tmp)-2, length(app.SplitEventsData.(['Channel_' int2str(channel)]).Timestamp));
            for i = 1:length(tmp)-3
                aux = strsplit(tmp{i}, '_');
                SelectedEventsMatrix(i,:) = app.SplitEventsData.(['Channel_' int2str(channel)]).(['Event_' aux{2}]);
            end

            result.Mean = mean(SelectedEventsMatrix, 1);           
            result.Timestamp = app.SplitEventsData.(['Channel_' int2str(channel)]).Timestamp;
        end
    end
           
    methods (Access = public)
        function writeCmdWindow(app, str)
            shell = [datestr(datetime("now"),13) ' > '];
            %             app.CmdWindow = [app.CmdWindow(:), shell str];
            app.Log = [shell char(str) '<br>'];
            app.HTML.Data = app.Log;
        end
        
        function UpdateRecords(app, SelectedRecord)
            % Salta cuando se selecciona el record en un OE que contiene
            % varios.
            OESession = Session(app.Folder);
            app.Data.LoadOELabData(OESession.recordNodes{SelectedRecord});
            ShowRecordingInfo(app)
            GenerateMultiChannelSelector(app);
        end
        
        function DeletePlotPlane(app, type)
            % Borra los plot planes abiertos hasta el momento
            app.PlotPlanes.(type) = [];
        end

        function DeletePlotGUI(app)
            app.PlotPlanes_2 = [];
        end
        
        function UpdateSIEParameters(app, Thresh, Promi, Start)
            % Actualiza los parametros del split in events sin triggers.
            app.SplitEventsParam.Threshold = Thresh;
            app.SplitEventsParam.Prominence = Promi;
            app.SplitEventsParam.StartPos = Start;
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ImportOpenEphysFilesMenu
        function ImportOpenEphysFilesMenuSelected(app, event)
            try
                writeCmdWindow(app, "Loading Open Ephys File")
                app.Folder = uigetdir();
                OESession = Session(app.Folder);

                % Está como invertido, es column, row
                app.ElectrodeType = "half";
                app.ElectrodeFootprint = ~mod((1:8)+(1:8)',2);
                app.ElectrodeFootprint(1,1) = 0;
                app.ElectrodeFootprint(end, 1) = 0;
                app.ElectrodeFootprint(1, end) = 0;
                app.ElectrodeFootprint(end, end) = 0;
                app.ElectrodeFootprint(8,4) = 0;
                app.ElectrodeFootprint(4,8) = 0;
                app.ElectrodeFootprint(4,4) = 1;
                app.ElectrodeFootprint(4,5) = 1;
                app.ElectrodeFootprint(4,5) = 1;
                app.ElectrodeFootprint(5,4) = 1;


                app.EditField.Value = 22;
                app.SelectedChannel = app.EditField.Value;

                if length(OESession.recordNodes) > 1
                    NodeNames = {};
                    for i = 1:length(OESession.recordNodes)
                        NodeNames{i}= OESession.recordNodes{i}.name;
                    end
                    disp(NodeNames)
                    app.DialogApp = DialogRecord(app, length(OESession.recordNodes), NodeNames);
                else
                    app.Data.LoadOELabData(OESession.recordNodes{1});
                    app.IsChannelVisible = true(GetChannelsNumber(app.Data));
                    ShowRecordingInfo(app)
                    GenerateMultiChannelSelector(app);
                    tmpStruct = struct("Units", GetChannelsUnits(app.Data));
                   
                end
                app.PlotPlanes_2 = PlotGui_v2(app, tmpStruct);
                writeCmdWindow(app, "done")
            catch ME
                writeCmdWindow(app, "error")
            end
                
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp)
%             tmp = fieldnames(app.PlotPlanes);
%             for i = 1:length(tmp)
%                 delete(app.PlotPlanes.(tmp{i}));
%             end
            delete(app.PlotPlanes_2)
            delete(app.ChangeParamsSIEApp)
            delete(app)
        end

        % Button pushed function: UpdateChannelPlotButton
        function UpdateChannelPlotButtonPushed(app, event)
            % Callback Channel Plot Button. Manda actualizar el plot de
            % single channel y actualiza la información de este canal. 
            app.SelectedChannel = app.EditField.Value;
            writeCmdWindow(app, ['Plotting Channel No. ' int2str(app.SelectedChannel)]);
            PlotSingle(app.PlotPlanes_2);            
            UpdateElectrodeInfo(app);
        end

        % Menu selected function: SplitSelectedChannel
        function SplitSelectedChannelMenuSelected(app, event)
            try
                writeCmdWindow(app, ['Splitting Channel No. ' int2str(app.SelectedChannel)]);
                app.SplitEventsData.(['Channel_' int2str(app.SelectedChannel)]) = DoSplit(app, app.SelectedChannel);
                GenerateEventsCheckBox(app.PlotPlanes_2)
                writeCmdWindow(app, "done");
            catch
                writeCmdWindow(app, "error");
            end
        end

        % Menu selected function: SplitAllChannels
        function SplitAllChannelsMenuSelected(app, event)
            try
                writeCmdWindow(app, "Splitting All Channels");
                for j = 1:size(app.ElectrodeFootprint, 1)
                    for i = 1:size(app.ElectrodeFootprint, 2)
                        if(app.ElectrodeFootprint(i,j) == 1)
                            app.SplitEventsData.(['Channel_' int2str(i) int2str(j)]) = DoSplit(app, str2double(strcat(num2str(i), num2str(j))));
                        end
                    end
                end
                writeCmdWindow(app, "done");
            catch ME
                writeCmdWindow(app, "error");
            end

        end

        % Button pushed function: UpdateEventsPlotButton
        function UpdateEventsPlotButtonPushed(app, event)
            writeCmdWindow(app, ['Plotting events of Channel No. ' int2str(app.SelectedChannel)]);
            try
                GenerateEventsCheckBox(app.PlotPlanes_2);
                PlotEvents(app.PlotPlanes_2);
                writeCmdWindow(app, "done");
            catch ME
                % To-Do habría que hacer un handler del error bien
                writeCmdWindow(app, ['Splitting events of Channel No. ' int2str(app.SelectedChannel)]);
                app.SplitEventsData.(['Channel_' int2str(app.SelectedChannel)]) = DoSplit(app, app.SelectedChannel);
                GenerateEventsCheckBox(app.PlotPlanes_2);
                PlotEvents(app.PlotPlanes_2);
                writeCmdWindow(app, "done");
            end
        end

        % Menu selected function: CalculateallchannelsEventsmeanMenu
        function CalculateallchannelsEventsmeanMenuSelected(app, event)
            writeCmdWindow(app, "Calculate all Ch Means")
            try
                for j = 1:size(app.ElectrodeFootprint, 1)
                    for i = 1:size(app.ElectrodeFootprint, 2)
                        if(app.ElectrodeFootprint(i,j) == 1)
                            app.SplitEventsData.(['Channel_' int2str(i) int2str(j)]).Event_mean = ...
                                CalculateChannelMean(app, app.SelectedChannel);
                        end
                    end
                end
            catch ME
                for j = 1:size(app.ElectrodeFootprint, 1)
                    for i = 1:size(app.ElectrodeFootprint, 2)
                        if(app.ElectrodeFootprint(i,j) == 1)
                            if(strcmp(ME.identifier, 'MATLAB:nonExistentField'))
                                app.SplitEventsData.(['Channel_' int2str(i) int2str(j)]) = ...
                                    SplitInEvents2(app.Data, i);
                                app.SplitEventsData.(['Channel_' int2str(i) int2str(j)]).Event_mean = ...
                                    CalculateChannelMean(app, app.SelectedChannel);
                            end
                        end
                    end
                end
            end
            writeCmdWindow(app, "Done")
            
        end

        % Menu selected function: SplitSelectedChannelNT
        function SplitSelectedChannelNTMenuSelected(app, event)
            app.SplitEventsData.(['Channel_' int2str(app.SelectedChannel)]) = ...
                SplitInEvents2(app.Data, app.SelectedChannel, app.SplitEventsParam);
        end

        % Menu selected function: SplitAllChannelsNT
        function SplitAllChannelsNTMenuSelected(app, event)
            for j = 1:size(app.ElectrodeFootprint, 1)
                for i = 1:size(app.ElectrodeFootprint, 2)
                    if(app.ElectrodeFootprint(i,j) == 1)
                        app.SplitEventsData.(['Channel_' int2str(i) int2str(j)]) = ...
                            SplitInEvents2(app.Data, i, app.SplitEventsParam);
                    end
                end
            end
        end

        % Menu selected function: ImportMCSFilesMenu
        function ImportMCSFilesMenuSelected(app, event)
            try
                writeCmdWindow(app, "Loading MCS Files")
                [file, location] = uigetfile('*.h5');
                % De momento suponemos que solo hay un record por objeto, si no
                % tendríamos que modificarlo y hacer algo parecido al anterior.

                cfg = [];
                cfg.dataType = 'raw';
                tmp = McsHDF5.McsData(fullfile(location, file), cfg);

                app.Data.LoadMCSLabData(tmp);
                ShowRecordingInfo(app)

                app.ElectrodeType = "full";
                app.ElectrodeFootprint = ones(8,8);
                app.ElectrodeFootprint(1,1) = 0;
                app.ElectrodeFootprint(end, 1) = 0;
                app.ElectrodeFootprint(1, end) = 0;
                app.ElectrodeFootprint(end, end) = 0;

                app.EditField.Value = 47;
                app.SelectedChannel = 47;
                GenerateMultiChannelSelector(app);

                app.SelectChannelEvent.Value = app.EventToPlot;
                app.SelecteventtoplotEditFieldLabel.Visible = "on";
                app.SelectChannelEvent.Visible = "on";

                tmpStruct = struct("Units", GetChannelsUnits(app.Data));
                app.IsChannelVisible = true(GetChannelsNumber(app.Data));
                app.PlotPlanes_2 = PlotGui_v2(app, tmpStruct);

                writeCmdWindow(app, "done")
            catch ME
                writeCmdWindow(app, "error")
            end
            
        end

        % Menu selected function: ChangeParametersMenu
        function ChangeParametersMenuSelected(app, event)
            app.ChangeParamsSIEApp = ChangeParametersSIE(app, app.SplitEventsParam);
        end

        % Value changed function: SelectChannelEvent
        function SelectChannelEventValueChanged(app, event)
            value = app.SelectChannelEvent.Value;
            app.EventToPlot = value;
            for j = 1:size(app.ElectrodeFootprint, 1)
                for i = 1:size(app.ElectrodeFootprint, 2)
                    if(app.ElectrodeFootprint(i,j) == 1)
                        if value == 0
                            %Cuando se selecciona el 0 es que se plotee la media en
                            %todos los eventos.
                            PlotChannel(app.PlotPlanes_2, ['Channel_' int2str(i) int2str(j)],...
                                Get1DChannelID(app.Data, str2double([int2str(i) int2str(j)])),...
                                app.SplitEventsData.(['Channel_' int2str(i) int2str(j)]).Event_mean.Mean,...
                                app.SplitEventsData.(['Channel_' int2str(i) int2str(j)]).Event_mean.Timestamp)
                        else
                            PlotChannel(app.PlotPlanes_2, ['Channel_' int2str(i) int2str(j)],...
                                Get1DChannelID(app.Data, str2double([int2str(i) int2str(j)])),...
                                app.SplitEventsData.(['Channel_' int2str(i) int2str(j)]).(['Event_' num2str(app.EventToPlot,"%02d")]),...
                                app.SplitEventsData.(['Channel_' int2str(i) int2str(j)]).Timestamp);
                        end
                    end
                end
            end

        end

        % Menu selected function: LoadMEAFootPrint
        function LoadMEAFootPrintMenuSelected(app, event)
            % Le pasamos como argumento un archivo .mat con una matriz con
            % las dimensiones de la huella. Se almacenan en una variable de
            % nombre "ElectrodeFootprint".
            writeCmdWindow(app, "Importing MEA FootPrint")
            try
                footprint = uigetfile('*.mat');
                app.ElectrodeFootprint = load(footprint, 'ElectrodeFootprint').ElectrodeFootprint;
                GenerateMultiChannelSelector(app);
                writeCmdWindow(app, "done")
            catch ME
                writeCmdWindow(app, "error")
            end
        end

        % Menu selected function: ImportPlatformFilesMenu
        function ImportPlatformFilesMenuSelected(app, event)
            % To-Do: Testear
            try
                writeCmdWindow(app, "Loading Platform files")
                FPGAFile = uigetfile('*.mat');
                tmp = load(FPGAFile).saveVar;
                % Se podría implementar seleccionar distintos records
                % To-Do: Que salte la pestaña de seleccionar records
                app.Data.LoadFPGALabData(tmp, 1)

                % Está como invertido, es column, row
                app.ElectrodeType = "half";
                app.ElectrodeFootprint = ~mod((1:8)+(1:8)',2);
                app.ElectrodeFootprint(1,1) = 0;
                app.ElectrodeFootprint(end, 1) = 0;
                app.ElectrodeFootprint(1, end) = 0;
                app.ElectrodeFootprint(end, end) = 0;
                app.ElectrodeFootprint(8,4) = 0;
                app.ElectrodeFootprint(4,8) = 0;
                app.ElectrodeFootprint(4,4) = 1;
                app.ElectrodeFootprint(4,5) = 1;
                app.ElectrodeFootprint(4,5) = 1;
                app.ElectrodeFootprint(5,4) = 1;


                aux = GetChannelsID(app.Data);
                app.EditField.Value = aux(1);
                app.SelectedChannel = app.EditField.Value;

                app.IsChannelVisible = true(GetChannelsNumber(app.Data));
                ShowRecordingInfo(app)
                GenerateMultiChannelSelector(app);

                tmpStruct = struct("Units", GetChannelsUnits(app.Data));
                app.PlotPlanes_2 = PlotGui_v2(app, tmpStruct);

                writeCmdWindow(app, "done")
            catch ME
                writeCmdWindow(app, "error")
            end
        end

        % Button pushed function: ShowchmeanButton
        function ShowchmeanButtonPushed(app, event)
            %Este botón te permite calcular la media de un solo canal de
            %todos los eventos

            %To-do: Que puedas elegir los eventos con los que quieres hacer
            %la media
            %To-do: Que no plotee varias veces la media de este evento
            writeCmdWindow(app, ['Mean of Channel No. ' int2str(app.SelectedChannel)]);
            try
                if(isempty(app.SplitEventsData.(['Channel_' int2str(app.SelectedChannel)]).Event_mean))
                    app.SplitEventsData.(['Channel_' int2str(app.SelectedChannel)]).Event_mean = CalculateChannelMean(app, app.SelectedChannel);
                    writeCmdWindow(app, "done, plotting mean")
                                   
                end

                enableMean(app.PlotPlanes_2)

                writeCmdWindow(app, "done")
            catch
                writeCmdWindow(app, "error")
            end
        end

        % Menu selected function: FeatureExtractorMenu
        function FeatureExtractorMenuSelected(app, event)
            % Callback que se ejecuta cuando se selecciona la opción en el
            % menú. Extrae las características de todos los eventos de un
            % canal. 
            writeCmdWindow(app, ['Extracting all features of Channel No. ' int2str(app.SelectedChannel)])
            try
                tmp = fieldnames(app.SplitEventsData.(['Channel_' int2str(app.SelectedChannel)]));
                for i = 1:length(tmp)-3
                    app.FeaturesStruct.(['Channel_' int2str(app.SelectedChannel)]).(tmp{i}) = ...
                        FeaturesExtractor(app.SplitEventsData.(['Channel_' int2str(app.SelectedChannel)]),...
                        app.SelectedChannel, tmp{i});
                end
                if not(isempty(app.SplitEventsData.(['Channel_' int2str(app.SelectedChannel)]).Event_mean))
                app.FeaturesStruct.(['Channel_' int2str(app.SelectedChannel)]).Mean = ...
                    FeaturesExtractor(app.SplitEventsData.(['Channel_' int2str(app.SelectedChannel)]).Event_mean,...
                    app.SelectedChannel, 'Mean');
                end

                writeCmdWindow(app, "done")
            catch
                writeCmdWindow(app, "error, try Split Events or mean before Features Extraction")
            end           

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.SelectedChannel = value;
        end

        % Menu selected function: OpenNewPlotPlaneMenu
        function OpenNewPlotPlaneMenuSelected(app, event)
            %Función que abre un plot en caso de que se haya cerrado
            writeCmdWindow(app, "Opening new plot window")
            if(isempty(app.PlotPlanes_2))
                tmpStruct = struct("Units", GetChannelsUnits(app.Data));
                app.PlotPlanes_2 = PlotGui_v2(app, tmpStruct);
                writeCmdWindow(app, "done")
            else
                writeCmdWindow(app, "error, plot window already exists")
            end
        end

        % Menu selected function: ClosePlotPlanesMenu
        function ClosePlotPlanesMenuSelected(app, event)
            %Función que permite cerrar desde el plot
            writeCmdWindow(app, "Closening plot window")
            if(isempty(app.PlotPlanes_2))
                writeCmdWindow(app, "error, plot window already closed")
            else
                closePlotGui(app.PlotPlanes_2);
                DeletePlotGUI(app);
                writeCmdWindow(app, "done")
            end            
        end

        % Button pushed function: ShowchFeaturesButton
        function ShowchFeaturesButtonPushed(app, event)
            DisplayFeaturesTable(app.PlotPlanes_2)
        end

        % Menu selected function: ExportDataTomatMenu
        function ExportDataTomatMenuSelected(app, event)
            % Se encarga de almacenar todos los datos de las capturas en formato
            % LabData para poder procesarlo luego en matlab, comparando
            % entre archivos de distintos formatos. También almacena los
            % datos de los eventos, características y medias en caso de que
            % se hayan calculado.
            filename = ['LABDATA_export_' datestr(datetime('now')) '.mat'];
            GeneratedData.Events = app.SplitEventsData();
            GeneratedData.Features = app.FeaturesStruct();
            GeneratedData.Mean = app.MeanStruct();
            importedData = app.Data;
            save(filename, "importedData", "GeneratedData");
            writeCmdWindow(app, ['Saved data to ' filename]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportOpenEphysFilesMenu = uimenu(app.FileMenu);
            app.ImportOpenEphysFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportOpenEphysFilesMenuSelected, true);
            app.ImportOpenEphysFilesMenu.Text = 'Import Open Ephys Files';
            app.ImportMCSFilesMenu = uimenu(app.FileMenu);
            app.ImportMCSFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMCSFilesMenuSelected, true);
            app.ImportMCSFilesMenu.Text = 'Import MCS Files';
            app.ImportPlatformFilesMenu = uimenu(app.FileMenu);
            app.ImportPlatformFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportPlatformFilesMenuSelected, true);
            app.ImportPlatformFilesMenu.Text = 'Import Platform Files';
            app.ExportDataTomatMenu = uimenu(app.FileMenu);
            app.ExportDataTomatMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDataTomatMenuSelected, true);
            app.ExportDataTomatMenu.Text = 'Export Data To .mat';
            app.PlotMenu = uimenu(app.UIFigure);
            app.PlotMenu.Text = 'Plot';
            app.OpenNewPlotPlaneMenu = uimenu(app.PlotMenu);
            app.OpenNewPlotPlaneMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenNewPlotPlaneMenuSelected, true);
            app.OpenNewPlotPlaneMenu.Text = 'Open New Plot Plane';
            app.ClosePlotPlanesMenu = uimenu(app.PlotMenu);
            app.ClosePlotPlanesMenu.MenuSelectedFcn = createCallbackFcn(app, @ClosePlotPlanesMenuSelected, true);
            app.ClosePlotPlanesMenu.Text = 'Close Plot Planes';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.SpliteventsusingexternaltriggersMenu = uimenu(app.OptionsMenu);
            app.SpliteventsusingexternaltriggersMenu.Text = 'Split events using external triggers';
            app.SplitSelectedChannel = uimenu(app.SpliteventsusingexternaltriggersMenu);
            app.SplitSelectedChannel.MenuSelectedFcn = createCallbackFcn(app, @SplitSelectedChannelMenuSelected, true);
            app.SplitSelectedChannel.Text = 'Split Selected Channel';
            app.SplitAllChannels = uimenu(app.SpliteventsusingexternaltriggersMenu);
            app.SplitAllChannels.MenuSelectedFcn = createCallbackFcn(app, @SplitAllChannelsMenuSelected, true);
            app.SplitAllChannels.Text = 'Split All Channels';
            app.SpliteventsNT = uimenu(app.OptionsMenu);
            app.SpliteventsNT.Text = 'Split events';
            app.SplitSelectedChannelNT = uimenu(app.SpliteventsNT);
            app.SplitSelectedChannelNT.MenuSelectedFcn = createCallbackFcn(app, @SplitSelectedChannelNTMenuSelected, true);
            app.SplitSelectedChannelNT.Text = 'Split Selected Channel';
            app.SplitAllChannelsNT = uimenu(app.SpliteventsNT);
            app.SplitAllChannelsNT.MenuSelectedFcn = createCallbackFcn(app, @SplitAllChannelsNTMenuSelected, true);
            app.SplitAllChannelsNT.Text = 'SplitAllChannels';
            app.ChangeParametersMenu = uimenu(app.SpliteventsNT);
            app.ChangeParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeParametersMenuSelected, true);
            app.ChangeParametersMenu.Text = 'Change Parameters';
            app.CalculateallchannelsEventsmeanMenu = uimenu(app.OptionsMenu);
            app.CalculateallchannelsEventsmeanMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateallchannelsEventsmeanMenuSelected, true);
            app.CalculateallchannelsEventsmeanMenu.Text = 'Calculate all channels Events mean';
            app.FeatureExtractorMenu = uimenu(app.OptionsMenu);
            app.FeatureExtractorMenu.MenuSelectedFcn = createCallbackFcn(app, @FeatureExtractorMenuSelected, true);
            app.FeatureExtractorMenu.Text = 'Feature Extractor';
            app.LoadMEAFootPrint = uimenu(app.OptionsMenu);
            app.LoadMEAFootPrint.MenuSelectedFcn = createCallbackFcn(app, @LoadMEAFootPrintMenuSelected, true);
            app.LoadMEAFootPrint.Text = 'Load MEA Footprint';
            app.GridWindow = uigridlayout(app.UIFigure);
            app.RecordInformationPanel = uipanel(app.GridWindow);
            app.RecordInformationPanel.Title = 'Record Information';
            app.GridRecordInformation = uigridlayout(app.RecordInformationPanel);
            app.RecordNameLabel = uilabel(app.GridRecordInformation);
            app.RecordNameLabel.Text = 'Record Name:';
            app.NameInfoLabel = uilabel(app.GridRecordInformation);
            app.NameInfoLabel.Visible = 'off';
            app.NameInfoLabel.Text = '';
            app.RecordPathLabel = uilabel(app.GridRecordInformation);
            app.RecordPathLabel.Text = 'Record Path:';
            app.RecordElectrodeLabel = uilabel(app.GridRecordInformation);
            app.RecordElectrodeLabel.Text = 'Eletrodes recorded:  ';
            app.ElectrodeInfoLabel = uilabel(app.GridRecordInformation);
            app.ElectrodeInfoLabel.Visible = 'off';
            app.ElectrodeInfoLabel.Text = '';
            app.ProgramLabel = uilabel(app.GridRecordInformation);
            app.ProgramLabel.Text = 'Program Info:';
            app.FilePathLabel = uilabel(app.GridRecordInformation);
            app.FilePathLabel.Text = 'FilePathLabel';
            app.ProgramInfoLabel = uilabel(app.GridRecordInformation);
            app.ProgramInfoLabel.Visible = 'off';
            app.ProgramInfoLabel.Text = '';
            app.FileInfoLabel = uilabel(app.GridRecordInformation);
            app.FileInfoLabel.Visible = 'off';
            app.FileInfoLabel.Text = '';
            app.SampleRateLabel = uilabel(app.GridRecordInformation);
            app.SampleRateLabel.Text = 'Sample Rate:';
            app.SampleInfoLabel = uilabel(app.GridRecordInformation);
            app.SampleInfoLabel.Visible = 'off';
            app.SampleInfoLabel.Text = '';
            app.PathInfoLabel = uilabel(app.GridRecordInformation);
            app.PathInfoLabel.Visible = 'off';
            app.PathInfoLabel.Text = '';
            app.DurationLabel = uilabel(app.GridRecordInformation);
            app.DurationLabel.Text = 'Sample length:';
            app.DurationInfoLabel = uilabel(app.GridRecordInformation);
            app.DurationInfoLabel.Text = '';
            app.ExternalTriggersLabel = uilabel(app.GridRecordInformation);
            app.ExternalTriggersLabel.Text = 'External Triggers';
            app.TriggersInfoLabel = uilabel(app.GridRecordInformation);
            app.TriggersInfoLabel.Visible = 'off';
            app.SingleChannelPlotPanel = uipanel(app.GridWindow);
            app.SingleChannelPlotPanel.Title = 'Single Channel Plot';
            app.GridSingleChannel = uigridlayout(app.SingleChannelPlotPanel);
            app.CurrentChannelLabel = uilabel(app.GridSingleChannel);
            app.CurrentChannelLabel.Text = 'Current Channel:';
            app.NumberOfEventsLabel = uilabel(app.GridSingleChannel);
            app.NumberOfEventsLabel.Text = 'Number of Events: ';
            app.EditField = uieditfield(app.GridSingleChannel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.UpdateChannelPlotButton = uibutton(app.GridSingleChannel, 'push');
            app.UpdateChannelPlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateChannelPlotButtonPushed, true);
            app.UpdateChannelPlotButton.Text = 'Update Channel Plot';
            app.UpdateEventsPlotButton = uibutton(app.GridSingleChannel, 'push');
            app.UpdateEventsPlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateEventsPlotButtonPushed, true);
            app.UpdateEventsPlotButton.Text = 'Update Events Plot';
            app.NOELabel = uilabel(app.GridSingleChannel);
            app.NOELabel.Text = 'Events';
            app.NOEInfoLabel = uilabel(app.GridSingleChannel);
            app.NOEInfoLabel.Visible = 'off';
            app.NOEInfoLabel.Text = '';
            app.ShowchmeanButton = uibutton(app.GridSingleChannel, 'push');
            app.ShowchmeanButton.ButtonPushedFcn = createCallbackFcn(app, @ShowchmeanButtonPushed, true);
            app.ShowchmeanButton.Text = 'Show ch. mean';
            app.ShowchFeaturesButton = uibutton(app.GridSingleChannel, 'push');
            app.ShowchFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowchFeaturesButtonPushed, true);
            app.ShowchFeaturesButton.Text = 'Show ch. Features';
            app.PanelMultihannel = uipanel(app.GridWindow);
            app.PanelMultihannel.Title = 'Multi Channel Plot';
            app.GridMultiChannel = uigridlayout(app.PanelMultihannel);
            app.SelecteventtoplotEditFieldLabel = uilabel(app.GridMultiChannel);
            app.SelecteventtoplotEditFieldLabel.Visible = 'off';
            app.SelecteventtoplotEditFieldLabel.Text = 'Select event to plot';
            app.SelectChannelEvent = uieditfield(app.GridMultiChannel, 'numeric');
            app.SelectChannelEvent.ValueChangedFcn = createCallbackFcn(app, @SelectChannelEventValueChanged, true);
            app.SelectChannelEvent.Visible = 'off';
            app.HTML = uihtml(app.GridWindow);
            app.HTML.HTMLSource = 'C:\Users\acast\Desktop\TFM\Software\Pruebas matlab\HtmlDisplay.html';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppGui_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end