classdef DialogRecord < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ConfirmationLabel         matlab.ui.control.Label
        FicheroseleccionadoLabel  matlab.ui.control.Label
        LoadButton                matlab.ui.control.Button
        InfoLabel                 matlab.ui.control.Label
        RecordNodesListBox        matlab.ui.control.ListBox
        RecordNodesListBoxLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
        nRecords
        RecordNames
        SelectedRecord
    end
    
    methods (Access = private)
        
        function displayItems(app)
            app.RecordNodesListBox.Items = app.RecordNames';
            app.RecordNodesListBox.ItemsData = app.RecordNames;
            app.RecordNodesListBox.Visible = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, RecordLen, RecordNames)
            app.CallingApp = caller;
            app.nRecords = RecordLen;
            app.RecordNames = RecordNames;

            displayItems(app)
        end

        % Value changed function: RecordNodesListBox
        function RecordNodesListBoxValueChanged(app, event)
            [~, index] = ismember(app.RecordNodesListBox.Value, app.RecordNodesListBox.Items);
            app.SelectedRecord = index;
            app.ConfirmationLabel.Text = app.RecordNodesListBox.Value;
            app.ConfirmationLabel.Visible = 'on';
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            UpdateRecords(app.CallingApp, app.SelectedRecord);
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RecordNodesListBoxLabel = uilabel(app.UIFigure);
            app.RecordNodesListBoxLabel.Text = 'Record Nodes';
            app.RecordNodesListBox = uilistbox(app.UIFigure);
            app.RecordNodesListBox.Items = {'Item 1'};
            app.RecordNodesListBox.ItemsData = {'a'};
            app.RecordNodesListBox.ValueChangedFcn = createCallbackFcn(app, @RecordNodesListBoxValueChanged, true);
            app.RecordNodesListBox.Value = {};
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = 'Se han detectado varios Records dentro de la misma sesión, seleccione uno.';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FicheroseleccionadoLabel = uilabel(app.UIFigure);
            app.FicheroseleccionadoLabel.Text = 'Fichero seleccionado:';
            app.ConfirmationLabel = uilabel(app.UIFigure);
            app.ConfirmationLabel.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogRecord(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end