classdef LoadFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        FileMenu         matlab.ui.container.Menu
        ImportfilesMenu  matlab.ui.container.Menu
        Menu2            matlab.ui.container.Menu
        csvLamp          matlab.ui.control.Lamp
        csvLampLabel     matlab.ui.control.Label
        hdf5Lamp         matlab.ui.control.Lamp
        hdf5LampLabel    matlab.ui.control.Label
        txtLamp          matlab.ui.control.Lamp
        txtLampLabel     matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function FileStruct = ReadH5File(app, filename)
            FileStruct = struct();

            % Obtienes la info del raiz y lees todos los atributos que tiene
            RootInfo = h5info(filename, "/");
            disp(["Root: " RootInfo.Name])
            for l = 1:length(RootInfo.Attributes)
                FileStruct.RootAttr.(RootInfo.Attributes(l).Name) = ...
                    RootInfo.Attributes(l).Value;
            end

            %Obtienes la info del Data y lees los atributos que tiene
            DataInfo = h5info(filename, "/Data");
            disp(["Data: " DataInfo.Name])
            for l = 1:length(DataInfo.Attributes)
                FileStruct.Data.dataAttr.(DataInfo.Attributes(l).Name) = ...
                    DataInfo.Attributes(l).Value;
            end

            % Dentro del DATA puede haber x records, haces un for para recorrer todos
            % los records que hay.
            for i = 1:length(DataInfo.Groups)
                % Abres una Struct para cada uno de los grupos Record que hay.
                [~,D] = fileparts(DataInfo.Groups(i).Name);
                FileStruct.Data.(D) = struct();

                % Obtienes los atributos del record
                Recinfo = h5info(filename, DataInfo.Groups(i).Name);
                disp(["Record: " Recinfo.Name])
                for l = 1:length(Recinfo.Attributes)
                    FileStruct.Data.(D).RecordAttr.(Recinfo.Attributes(l).Name) = ...
                        Recinfo.Attributes(l).Value;
                end

                %Dentro de cada Record puede haber distintas carpetas, que no tienen
                %atributos. Se lee cuantas de estas carpetas hay y se crea una struct
                %para cada una.
                for j = 1:length(Recinfo.Groups)
                    [~, E] = fileparts(Recinfo.Groups(j).Name);
                    FileStruct.Data.(D).(E) = struct();

                    %Sacas el número de Streams dentro de cada carpeta. Habres una
                    %struct para cada uno.
                    StreamInfo = h5info(filename, Recinfo.Groups(j).Name);
                    for k = 1:length(StreamInfo.Groups)
                        [~, F] = fileparts(Recinfo.Groups(j).Name);
                        FileStruct.Data.(D).(E).(F) = struct();

                        StreamXInfo = h5info(filename, StreamInfo.Groups(k).Name);
                        %Obtenemos los atributos de Stream_x
                        for l = 1:length(StreamXInfo.Attributes)
                            FileStruct.Data.(D).(E).(F).StreamXAttr.(StreamXInfo.Attributes(l).Name) ...
                                = StreamXInfo.Attributes(l).Value;
                        end

                        %Obtenemos los datasets y para cada dataset lo copiamos a
                        %nuestra estructura.
                        for l = 1:length(StreamXInfo.Datasets)
                            FileStruct.Data.(D).(E).(StreamXInfo.Datasets(l).Name) = ...
                                h5read(filename, [StreamXInfo.Name '/' StreamXInfo.Datasets(1).Name]);
                        end
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ImportfilesMenu
        function ImportfilesMenuSelected(app, event)
            [file, path, index] = uigetfile({'*.txt', 'Text Files (*.txt)'; ...
                                            '*.h5;*.hdf5',...
                                            'HDF5 Files (*.h5,*.hdf5)'; ...
                                            '*.csv', 'CSV Files (*.csv)'}, ...
                                            'Import File');
            if isequal(file,0)
                disp('User selected Cancel')
            else
                disp(['User selected ', fullfile(path, file),... 
                    ' and filter index: ', num2str(index)])
            end

            switch index
                case 1
                    app.txtLamp.Enable = 'on';
                case 2
                     app.hdf5Lamp.Enable = 'on';
                     DataStruct = ReadH5File(app, file);
                case 3
                     app.csvLamp.Enable = 'on';
                otherwise 
                    disp('File Not supported.')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportfilesMenu = uimenu(app.FileMenu);
            app.ImportfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportfilesMenuSelected, true);
            app.ImportfilesMenu.Accelerator = 'I';
            app.ImportfilesMenu.Text = 'Import files';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.txtLampLabel = uilabel(app.UIFigure);
            app.txtLampLabel.Enable = 'off';
            app.txtLampLabel.Text = 'txt';
            app.txtLamp = uilamp(app.UIFigure);
            app.txtLamp.Enable = 'off';
            app.hdf5LampLabel = uilabel(app.UIFigure);
            app.hdf5LampLabel.Enable = 'off';
            app.hdf5LampLabel.Text = 'hdf5';
            app.hdf5Lamp = uilamp(app.UIFigure);
            app.hdf5Lamp.Enable = 'off';
            app.csvLampLabel = uilabel(app.UIFigure);
            app.csvLampLabel.Enable = 'off';
            app.csvLampLabel.Text = 'csv';
            app.csvLamp = uilamp(app.UIFigure);
            app.csvLamp.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadFile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end